classdef H2_Fitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        ExportLabel               matlab.ui.control.Label
        ExportDatafromPlotButton  matlab.ui.control.Button
        ResetTableButton          matlab.ui.control.Button
        MeanLabel                 matlab.ui.control.Label
        Mean                      matlab.ui.container.GridLayout
        Slope                     matlab.ui.control.Label
        StdDev                    matlab.ui.control.Label
        SaveSlopetoTableButton    matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        LoadDataButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        VolumeEditField           matlab.ui.control.NumericEditField
        VolumemLLabel             matlab.ui.control.Label
        YAxisDataButtonGroup      matlab.ui.container.ButtonGroup
        nmolButton                matlab.ui.control.RadioButton
        mVButton                  matlab.ui.control.RadioButton
        molLButton                matlab.ui.control.RadioButton
        InterceptEditField        matlab.ui.control.NumericEditField
        InterceptEditFieldLabel   matlab.ui.control.Label
        ProcessCalcLabel          matlab.ui.control.Label
        SlopeEditField            matlab.ui.control.NumericEditField
        SlopeEditFieldLabel       matlab.ui.control.Label
        SpanButton                matlab.ui.control.Button
        xLabel_4                  matlab.ui.control.Label
        UpperXEditField           matlab.ui.control.NumericEditField
        xLabel_3                  matlab.ui.control.Label
        LowerXEditField           matlab.ui.control.NumericEditField
        FitEquationTextArea       matlab.ui.control.TextArea
        FitEquationTextAreaLabel  matlab.ui.control.Label
        UpperYEditField           matlab.ui.control.NumericEditField
        UpperYEditFieldLabel      matlab.ui.control.Label
        AxesLimitsLabel           matlab.ui.control.Label
        LowerYEditField           matlab.ui.control.NumericEditField
        LowerYEditFieldLabel      matlab.ui.control.Label
        FitLimitsLabel            matlab.ui.control.Label
        xLabel                    matlab.ui.control.Label
        LowerFit                  matlab.ui.control.NumericEditField
        xLabel_2                  matlab.ui.control.Label
        UpperFit                  matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Path % Store path to current directory
        bothY % Store Raw and Processed traces of ydata
        YData % Store the linear YData from table
        XData % Store the uniform log Xdata
        xPoints % Store the x-data points of ROI object
        xData % Store excluded xData points

        ROILine = gobjects(2,1);
        Shade % Store shade array
        ShadeArea % Store shade area struct
        FitResult % Store the fit struct
        p1 % Store fit slope

        yFitData % Store fitted Ydata

        XMin % Store minimum of uniform Xdata
        XMax % Store maximum of uniform Xdata
        Curve % Store fit plot
        Data % Store scatter plot
        LogPlot % Store plot variable only when log data is loaded in

        Text % Store rate variable in text object (label)
        leftX % Store left area text object (label)
        rightX % Store right area text object (label)
        CenterY % Hold the center of all the data for labels and such
        %         gof % Holds std. dev of each fit
        processY % Property to hold the calculated YData from raw
        YMax % Hold maximum value of y for limits
        YMin % Hold minimum vlaue of y for limits
    end

    methods (Access = private)
        function createFit(app)
            % Fit: 'Lin-Lin H2 Graph'
            % Set up fittype and options.
            excludedPoints = (app.XData < app.xPoints(1,1)) | (app.XData > app.xPoints(2,1));
            includedPoints = ~excludedPoints;

            app.xData = app.XData(includedPoints);
            yData = app.YData(includedPoints);

            opts = fitoptions( 'Method', 'LinearLeastSquares');
            opts.Robust = 'Bisquare';
            %             opts.Exclude = excludedPoints;

            % Fit model to data and save data to vars
            app.FitResult = fit(app.xData, yData, 'poly1', opts);
%                         app.yFitData = feval(app.FitResult, app.XData);
            app.yFitData = feval(app.FitResult,app.xData);
            app.p1 = app.FitResult.p1;
            % Turn CFIT object into string
            fchar = evalc('app.FitResult');
            fstring = convertCharsToStrings(fchar);
            app.FitEquationTextArea.Value = fstring;
        end

        function shader(app)

            app.Shade = [];
            app.xPoints = [];

            %%%%% Default shaded area drawn/data & model
            app.XMin = min(app.XData);
            app.XMax = max(app.XData);

            app.YMin = min(app.YData);
            app.YMax = max(app.YData);

            % Draw both shaded areas up until XLims
            RGarea = app.ShadeArea;

            % Create new areas
            app.Shade = gobjects(2,1);
            app.Shade(1,1) = rectangle(app.UIAxes,"Position", [app.XMin app.YMin RGarea(1,1)-app.XMin app.YMax-app.YMin],'FaceColor',[0.75 0.75 0.75]);
            app.Shade(2,1) = rectangle(app.UIAxes,"Position", [RGarea(2,1) app.YMin app.XMax-RGarea(2,1) app.YMax-app.YMin],'FaceColor',[0.75 0.75 0.75]);

            % Set default axes boundaries
            app.UIAxes.XLim = [app.XMin app.XMax];
            app.UIAxes.YLim = [app.YMin app.YMax];

            % Store Lower and Upper X coordinates
            app.xPoints(1,1) = RGarea(1,1);
            app.xPoints(2,1) = RGarea(2,1);

            %%%%% End of shaded area creation
            createFit(app);
            plotModel(app);
            plotData(app);

            % Create labels
            rate = sprintf('rate=%.2f',app.p1);

            app.Text = text(app.UIAxes, app.xPoints(2,1)*1.05, (0.90)*app.UIAxes.YLim(2), rate, 'FontWeight', 'bold');
            app.leftX = text(app.UIAxes, app.ShadeArea(1,1)*0.90, app.CenterY, sprintf('%.2f',app.ShadeArea(1,1)), 'Color', 'k', 'FontWeight',"bold", 'HorizontalAlignment',"right");
            app.rightX = text(app.UIAxes, app.ShadeArea(2,1)*1.05, app.CenterY, sprintf('%.2f',app.ShadeArea(2,1)), 'Color', 'k', 'FontWeight',"bold", 'HorizontalAlignment',"left");

            % Implement drawline for either side of the area
            x = app.UIAxes.XLim(1);
            y = app.UIAxes.YLim(1)-1;
            w = app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
            h = (app.UIAxes.YLim(2)*1.01)-(app.UIAxes.YLim(1)-1);

            % Create ROI lines
            app.ROILine(1,1) = images.roi.Line(app.UIAxes, "Position", [app.xPoints(1,1) app.UIAxes.YLim(1)-1; app.xPoints(1,1) app.UIAxes.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0], "DrawingArea", [x y w h],'Tag','Left');
            app.ROILine(2,1) = images.roi.Line(app.UIAxes, "Position", [app.xPoints(2,1) app.UIAxes.YLim(1)-1; app.xPoints(2,1) app.UIAxes.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0], "DrawingArea", [x y w h],'Tag','Right');

            % Add callbacks for when point moves and is let go
            addlistener(app.ROILine(1,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));
            addlistener(app.ROILine(2,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));

            addlistener(app.ROILine(1,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));
            addlistener(app.ROILine(2,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));
        end

        function default(app)
            % Update both shaded areas up until XLims
            RGarea = app.ShadeArea;

            height = app.YMax-app.YMin;
            % Update new areas
            app.Shade(1,1).Position = [app.XMin app.YMin RGarea(1,1)-app.XMin height];
            app.Shade(2,1).Position = [RGarea(2,1) app.YMin app.XMax-RGarea(2,1) height];

            % Store Lower and Upper X coordinates
            app.xPoints(1,1) = RGarea(1,1);
            app.xPoints(2,1) = RGarea(2,1);
        end


        function plotModel(app)
            % Plot the model and its limits
            delete(app.Curve);
            app.Curve = line(app.UIAxes, app.xData, app.yFitData, 'Color', [1 0 0],'LineWidth',1);

            % Update Fit Limits
            app.UpperFit.Value = app.xPoints(1,1);
            app.LowerFit.Value = app.xPoints(2,1);
        end

        function plotData(app)
            % Plot the data as scatter
            delete(app.Data);
            app.Data = line(app.UIAxes, app.XData, app.YData);
        end


        function updateLabels(app)
            % Gather updated data for updated slope annotation
            rate = sprintf('rate=%.2f',app.p1);

            % Change label values accordingly to the moved x pos
            % Left-hand label
            if app.ShadeArea(1,1) < 1.25*app.UIAxes.XLim(1) % If ROI crosses threshold
                app.leftX.Position = [app.ShadeArea(1,1)*1.15, app.CenterY];
            elseif app.UIAxes.XLim(1) == 0 && app.ShadeArea(1,1) < app.UIAxes.XTick(2)/2
%                 mag = floor(log(abs(app.UIAxes.XLim(2)))./log(10));
%                 tst = app.UIAxes.XTick(2);
                app.leftX.Position = [app.UIAxes.XTick(2), app.CenterY];
            else
                app.leftX.Position = [app.ShadeArea(1,1)*0.90, app.CenterY];
            end

            % Right-hand labels
            if app.ShadeArea(2,1) > 0.9*app.UIAxes.XLim(2) % If ROI crosses threshold
                app.rightX.Position = [app.ShadeArea(2,1)*0.87, app.CenterY];
                app.Text.Position = [app.xPoints(2,1)*0.87 (0.90)*app.UIAxes.YLim(2)];
            else
                app.rightX.Position = [app.ShadeArea(2,1)*1.05, app.CenterY];
                app.Text.Position = [app.xPoints(2,1)*1.05 (0.90)*app.UIAxes.YLim(2)];
            end

            % Change label for slope
            app.Text.String = rate;
            app.leftX.String = sprintf('%.2f',app.ShadeArea(1,1));
            app.rightX.String = sprintf('%.2f',app.ShadeArea(2,1));
        end

        function updateModel(app)
            %             app.Curve.XData = app.XData; % Full fit data
            app.Curve.XData = app.xData;
            app.Curve.YData = app.yFitData;

            app.UpperFit.Value = app.xPoints(1,1);
            app.LowerFit.Value = app.xPoints(2,1);
        end

        function ROIChangedCallback(app,src,event)
            switch event.EventName
                case 'MovingROI'
                    % If ROI area is changed through manual mouse input
                    % Replace the position of the shaded area to moved ROI pos

                    if src.Tag == "Left"
                        app.ShadeArea(1,1) = event.CurrentPosition(1);
                    else
                        app.ShadeArea(2,1) = event.CurrentPosition(2);
                    end

                    % Continue as normal
                    default(app);
                    updateLabels(app);

                case 'ROIMoved'
                    createFit(app);
                    updateModel(app);

                    % Update only new slope string
                    rate = sprintf('rate=%.2f',app.p1);
                    app.Text.String = rate;
            end
        end


        function clearProperties(app)
            % Clear app and set axes properties
            cla(app.UIAxes,"reset");
            app.UIAxes.Title.String = 'H2 Fitting';
            app.UIAxes.YLabel.String = 'μmol/L or mV';
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UITable.ColumnFormat = {'logical','numeric','numeric','char'};
            app.UITable.Data = [];
            app.UITable.Data{6,4} = [];
            app.FitEquationTextArea.Value = "";
            app.SaveSlopetoTableButton.UserData = 0;
            app.Slope.Text = "Slope: ";
            app.StdDev.Text = "Std. Dev: ";
            app.UpperFit.Value = 0;
            app.LowerFit.Value = 0;
            app.LowerXEditField.Value = 0;
            app.UpperXEditField.Value = 0;
            app.LowerYEditField.Value = 0;
            app.UpperYEditField.Value = 0;
            app.SlopeEditField.Value = 0;
            app.InterceptEditField.Value = 0;
            app.VolumeEditField.Value = 0;

            app.ShadeArea = [];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Check if MATLAB addons are installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox');
                matlab.addons.isAddonEnabled('Curve Fitting Toolbox');
            catch error
                switch error.identifier
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        uialert(app.UIFigure,"Please ensure the 'Image Processing Toolbox' and 'Curve Fitting Toolbox' are installed to continue","Addon Required","Icon","error");
                        return;
                end
            end

            app.ExportLabel.Visible = false;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx';'*.csv';'*.xlsm';'*.xls';'*.slx';'*.*'},'File Selector');
            filevar = fullfile(path,file);
            app.Path = path;

            if file == 0
                return;
            else
                clearProperties(app);
                % H2Table = readtable(filevar); For full table
                % Or just use options to select specific columns
                opts = detectImportOptions(filevar);
                opts.SelectedVariableNames = opts.SelectedVariableNames([3,4,5]); % Only useful x and y data`
                opts.VariableNamingRule = "preserve";

                LinData = readtable(filevar,opts);

                app.XData = table2array(LinData(:,1));
                app.bothY = table2array(LinData(:,2:3)); % COLLECT RAW AND PROCESSED. FIGURE OUT HOW TO DISTINGUISH EFFICIENTLY

                if ~all(app.bothY(:,2))
                    app.YData = app.bothY(:,1);
                    %                     app.RawSwitch.Value = "On";
                    app.YAxisDataButtonGroup.SelectedObject = app.mVButton;
                    app.UIAxes.YLabel.String = "mV";
                    app.molLButton.Enable = "off"; % First time load of now processed data
                    app.nmolButton.Enable = "off";
                    app.SlopeEditField.Enable = "on";
                    app.InterceptEditField.Enable = "on";
                else
                    app.YData = app.bothY(:,2);
                    %                     s.Value = "Off";
                    app.YAxisDataButtonGroup.SelectedObject = app.molLButton;
                    app.UIAxes.YLabel.String = "μmol/L";
                    app.molLButton.Enable = "on";
                    app.nmolButton.Enable = "on";
                    app.SlopeEditField.Enable = "off";
                    app.InterceptEditField.Enable = "off";
                    %                     originalYProc = app.YData;
                end

                %                 app.IndexSize = width(LinData);
                app.YMin = min(app.YData);
                app.YMax = max(app.YData);
                app.CenterY = mean(app.YData);
                CenterTime = mean(app.XData);

                if CenterTime > 0
                    app.ShadeArea = [CenterTime/10; CenterTime+100];
                else
                    app.ShadeArea = [CenterTime+100; CenterTime/10];
                end

                shader(app);

                % Set GUI elements to reflect data limits
                app.LowerYEditField.Value = app.UIAxes.YLim(1);
                app.UpperYEditField.Value = app.UIAxes.YLim(2);
                app.LowerXEditField.Value = app.UIAxes.XLim(1);
                app.UpperXEditField.Value = app.UIAxes.XLim(2);
            end
        end

        % Button pushed function: SaveSlopetoTableButton
        function SaveSlopetoTableButtonPushed(app, event)
            app.SaveSlopetoTableButton.UserData = app.SaveSlopetoTableButton.UserData + 1;

            % Extract slope & calc. std. dev.
            coeffvals = coeffvalues(app.FitResult);
            slope = coeffvals(1);

            units = [app.UIAxes.YLabel.String, ' * 1/s'];

            % Create table entry
            data = {1,app.SaveSlopetoTableButton.UserData,slope,units};
            app.UITable.Data(app.SaveSlopetoTableButton.UserData,:) = data;

            % Find only the checked rows
            k = find(cell2mat(app.UITable.Data(:,1)));

            % Find mean slope
            avgslope = cell2mat(app.UITable.Data(k,3));
            meanslope = sum(avgslope,'all')/size(avgslope,1);

            % Find deviation from selected slopes
            dev = std(avgslope);

            app.Slope.Text = "Slope: " + num2str(meanslope,'%.4f');
            app.StdDev.Text = "Std. Dev: " + num2str(dev,'%.4f');
        end

        % Button pushed function: ExportDatafromPlotButton
        function ExportDatafromPlotButtonPushed(app, event)
            % Code to snap to nearest data point
            [~, lowerind] = min(abs(app.XData - app.LowerXEditField.Value));
            [~, upperind] = min(abs(app.XData - app.UpperXEditField.Value));
            
%             lowery = app.LowerYEditField.Value;
%             uppery = app.UpperYEditField.Value;

            time = app.XData(lowerind:upperind);
            concentration = app.YData(lowerind:upperind);

            yfit = feval(app.FitResult,app.XData);
            fitwindow = [app.xPoints(1,1); app.xPoints(2,1)];
            fiteqn = [app.FitResult.p1; app.FitResult.p2];

            % Create blank array with titles
            p = {'Time(s)', app.UIAxes.YLabel.String, 'yfitData', 'fitWindow', 'fitEquation'};
            A = nan(size(time,1),5);
            A(1:length(time),1) = time;
            A(1:length(concentration),2) = concentration;
            A(1:length(yfit),3) = yfit;
            A(1:length(fitwindow),4) = fitwindow;
            A(1:length(fiteqn),5) = fiteqn;

            % Convert 5 column vectors into table with appropriate names
            T = array2table(A);
            T.Properties.VariableNames(1:5) = p;

            % Add wavelength into title of filename
            %             f = sprintf('H2LinData%s.csv',app.WavelengthText.Text);
            f = 'H2LinData.csv';
            place = fullfile(app.Path,f);
            writetable(T,place);

            app.ExportLabel.Visible = true;
            app.ExportLabel.Text = strcat('Exported to: ',place);
            pause(5);
            try
                app.ExportLabel.Visible = false;
            catch
                return;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %             indices = event.Indices;
            %             newData = event.NewData;

            %             % Find size of fit data being used to calc std. dev.
            %             [~, line_index1] = min(abs(app.XData - app.xPoints(1,1)));
            %             [~, line_index2] = min(abs(app.XData - app.xPoints(1,2)));
            %             N = size(app.yFitData(line_index1:line_index2),1);


            % Find only the checked rows
            k = find(cell2mat(app.UITable.Data(:,1)));

            % Find mean slope
            avgslope = cell2mat(app.UITable.Data(k,3));
            meanslope = sum(avgslope,'all')/size(avgslope,1);

            %             % Find mean dev
            %             avgdev = cell2mat(app.UITable.Data(k,4));
            %             meandev = sum(avgdev,'all')/size(avgdev,1);
            dev = std(avgslope);

            app.Slope.Text = "Slope: " + num2str(meanslope,'%.4f');
            app.StdDev.Text = "Std. Dev: " + num2str(dev,'%.4f');
        end

        % Button pushed function: ResetTableButton
        function ResetTableButtonPushed(app, event)
            app.UITable.Data = [];
            app.UITable.Data{6,4} = [];
            app.SaveSlopetoTableButton.UserData = 0;

            app.Slope.Text = "Slope: ";
            app.StdDev.Text = "Std. Dev: ";
        end

        % Value changed function: UpperFit
        function UpperFitValueChanged(app, event)
            %             value = app.UpperFit.Value;
            latestvalue = event.Value;

            % Keep the changed input within the boundary conditons
            if (latestvalue < app.XMin)
                latestvalue = app.XMin; %#ok<NASGU>
            elseif (latestvalue > app.ROILine(2,1).Position(1))
                latestvalue = app.ROILine(2,1).Position(1) + 1; %#ok<NASGU>
            else
                % Code to snap to nearest data point
                app.xPoints(1,1) = latestvalue;
                [~, line_index] = min(abs(app.XData - latestvalue));
                closest = app.XData(line_index);

                app.ROILine(1,1).Position = [closest app.UIAxes.YLim(1)-1; closest app.UIAxes.YLim(2)*1.01];
                %                 app.ROILine(1,1).Label = sprintf('%.6f',closest);

                app.ShadeArea = [closest; app.xPoints(2,1)];

                updateLabels(app);
                default(app);
                createFit(app);
                updateModel(app);
            end
        end

        % Value changed function: LowerFit
        function LowerFitValueChanged(app, event)
            %             value = app.LowerFit.Value;
            latestvalue = event.Value;

            % Keep the changed input within the boundary conditons
            if (latestvalue > app.XMax)
                latestvalue = app.XMax; %#ok<NASGU>
            elseif (latestvalue < app.ROILine(1,1).Position(1))
                latestvalue = app.ROILine(1,1).Position(1) - 1; %#ok<NASGU>
            else
                % Code to snap to nearest data point
                app.xPoints(2,1) = latestvalue;

                [~, line_index] = min(abs(app.XData - latestvalue));
                closest = app.XData(line_index);

                app.ROILine(2,1).Position = [closest app.UIAxes.YLim(1)-1; closest app.UIAxes.YLim(2)*1.01];
                %                 app.ROILine(2,1).Label = sprintf('%.6f',closest);

                app.ShadeArea = [app.xPoints(1,1); closest];
                updateLabels(app);
                default(app);
                createFit(app);
                updateModel(app);
            end
        end

        % Selection changed function: YAxisDataButtonGroup
        function YAxisDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.YAxisDataButtonGroup.SelectedObject;

            switch selectedButton.Text
                case "μmol/L"
                    if all(app.bothY(:,2))
                        app.YData = app.bothY(:,2);
                        app.UIAxes.YLabel.String = 'μmol/L';
                    else
                        uialert(app.UIFigure,"The processed trace is empty, please calculate with calibration. Reverting back to raw trace.","Process Check","Icon","warning");
                        return;
                    end
                case "mV"
                    app.YData = app.bothY(:,1);
                    app.UIAxes.YLabel.String = 'mV';

                case "nmol"
                    value = app.VolumeEditField.Value;
                    if value == 0
                        app.YData = app.bothY(:,2);
                        app.UIAxes.YLabel.String = "μmol/L";
                        app.YAxisDataButtonGroup.SelectedObject = app.molLButton;
                        uialert(app.UIFigure,"The volume is empty, please change to a positive value. Reverting back to last selection.","Volume Check","Icon","warning");
                    else
                        app.bothY(:,3) = value*app.bothY(:,2);
                        app.YData = app.bothY(:,3);
                        app.Data.YData = app.YData/1000;
                        app.YAxisDataButtonGroup.SelectedObject = app.nmolButton;
                        app.UIAxes.YLabel.String = "nmol";
                    end
            end

            % Update plot YData
            app.Data.YData = app.YData;
            app.YMax = max(app.YData);
            app.YMin = min(app.YData);

            RGarea = app.ShadeArea;

            createFit(app);
            updateModel(app);

            % Update the YLims and reflect on GUI elements
            app.UIAxes.YLim(2) = max(app.YData);
            app.UIAxes.YLim(1) = min(app.YData);
            app.UpperYEditField.Value = app.UIAxes.YLim(2);
            app.LowerYEditField.Value = app.UIAxes.YLim(1);

            updateLabels(app);

            % Update shade areas and line to new YLims
            app.Shade(1,1).Position = [app.XMin app.UIAxes.YLim(1) RGarea(1,1)-app.XMin app.YMax-app.UIAxes.YLim(1)];
            app.Shade(2,1).Position = [RGarea(2,1) app.UIAxes.YLim(1) app.XMax-RGarea(2,1) app.YMax-app.UIAxes.YLim(1)];

            app.ROILine(1,1).Position = [app.xPoints(1,1) app.UIAxes.YLim(1)-1; app.xPoints(1,1) app.UIAxes.YLim(2)*1.01];
            app.ROILine(2,1).Position = [app.xPoints(2,1) app.UIAxes.YLim(1)-1; app.xPoints(2,1) app.UIAxes.YLim(2)*1.01];
        end

        % Value changed function: LowerXEditField
        function LowerXEditFieldValueChanged(app, event)
            value = app.LowerXEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.XLim(1) = value;
                %                 app.Shade(1,1).Position(1) = value;
                %                 app.Shade(1,1).Position(3) = abs(value - app.xPoints(1,1));
            end
        end

        % Value changed function: UpperXEditField
        function UpperXEditFieldValueChanged(app, event)
            value = app.UpperXEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.XLim(2) = value;
                %                 app.Shade(2,1).Position(3) = app.Shade(2,1).Position(1) - value;
            end
        end

        % Value changed function: LowerYEditField
        function LowerYEditFieldValueChanged(app, event)
            value = app.LowerYEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.YLim(1) = value;
            end
        end

        % Value changed function: UpperYEditField
        function UpperYEditFieldValueChanged(app, event)
            value = app.UpperYEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.YLim(2) = value;
            end
        end

        % Button pushed function: SpanButton
        function SpanButtonPushed(app, event)
            % Automatically find limitx excluding fit model
            app.UIAxes.XLimMode = "auto";
            app.UIAxes.XLimitMethod = "tight";

            app.Curve.Visible = false; % Remove since it spans all data points

            app.UIAxes.YLimMode = "auto";
            app.UIAxes.YLimitMethod = "tight";


            % Apply these limits to reflect GUI values
            app.LowerXEditField.Value = app.UIAxes.XLim(1);
            app.UpperXEditField.Value = app.UIAxes.XLim(2);
            app.LowerYEditField.Value = app.UIAxes.YLim(1);
            app.UpperYEditField.Value = app.UIAxes.YLim(2);

            % Revert back to manual so when the model changes it doesn't
            % change limits
            app.UIAxes.XLimMode = "manual";
            app.UIAxes.YLimMode = "manual";
            app.Curve.Visible = true;
        end

        % Value changed function: SlopeEditField
        function SlopeEditFieldValueChanged(app, event)
            value = app.SlopeEditField.Value;

            if value == 0
                return;
            else
                app.molLButton.Enable = "on";
                app.YAxisDataButtonGroup.SelectedObject = app.molLButton;

                app.bothY(:,2) = (app.bothY(:,1) - app.InterceptEditField.Value)/app.SlopeEditField.Value;
                app.YData = app.bothY(:,2);

                YAxisDataButtonGroupSelectionChanged(app);
            end
        end

        % Value changed function: InterceptEditField
        function InterceptEditFieldValueChanged(app, event)
            %             value = app.InterceptEditField.Value;
            app.bothY(:,2) = (app.bothY(:,1) - app.InterceptEditField.Value)/app.SlopeEditField.Value;
            app.YData = app.bothY(:,2);
            YAxisDataButtonGroupSelectionChanged(app);
        end

        % Value changed function: VolumeEditField
        function VolumeEditFieldValueChanged(app, event)
            %                         value = app.VolumeEditField.Value;
            app.YAxisDataButtonGroup.SelectedObject = app.nmolButton;
            YAxisDataButtonGroupSelectionChanged(app);
        end

        % Callback function
        function RawSwitchValueChanged(app, event)
            %             value = app.RawSwitch.Value;
            %
            %             if value == "Off"
            %                 if all(app.bothY(:,2))
            %                     app.YData = app.bothY(:,2);
            %                     app.UIAxes.YLabel.String = 'μmol/L';
            %                 else
            %                     uialert(app.UIFigure,"The processed trace is empty. Reverting back to raw trace.","Process Check","Icon","warning");
            %                     return;
            %                 end
            %             else
            %                 app.YData = app.bothY(:,1);
            %                 app.UIAxes.YLabel.String = 'mV';
            %             end
            %
            %             app.Data.YData = app.YData;
            %
            %             createFit(app);
            %             updateModel(app);
            %
            %             % Update the YLims and reflect on GUI elements
            %             app.UIAxes.YLim(2) = max(app.YData);
            %             app.UIAxes.YLim(1) = min(app.YData);
            %             app.UpperYEditField.Value = app.UIAxes.YLim(2);
            %             app.LowerYEditField.Value = app.UIAxes.YLim(1);
            %
            %             % Update shade areas and line to new YLims
            %             app.Shade{1}.YData = [app.UIAxes.YLim(1) app.UIAxes.YLim(1)];
            %             app.Shade{2}.YData = [app.UIAxes.YLim(1) app.UIAxes.YLim(1)];
            %
            %             app.ROILine(1,1).Position = [app.xPoints(1,1) app.UIAxes.YLim(1)-1; app.xPoints(1,1) app.UIAxes.YLim(2)*1.01];
            %             app.ROILine(2,1).Position = [app.xPoints(1,2) app.UIAxes.YLim(1)-1; app.xPoints(1,2) app.UIAxes.YLim(2)*1.01];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {530, 530};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'H2 Fitting';
            app.UIFigure.Icon = 'logo.png';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'✓'; 'Save'; 'Slope'; 'Units'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SaveSlopetoTableButton = uibutton(app.GridLayout2, 'push');
            app.SaveSlopetoTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSlopetoTableButtonPushed, true);
            app.SaveSlopetoTableButton.Text = 'Save Slope to Table';
            app.Mean = uigridlayout(app.GridLayout2);
            app.StdDev = uilabel(app.Mean);
            app.StdDev.Text = 'Std. Dev:';
            app.Slope = uilabel(app.Mean);
            app.Slope.Text = 'Slope:';
            app.MeanLabel = uilabel(app.GridLayout2);
            app.MeanLabel.Text = 'MEAN';
            app.ResetTableButton = uibutton(app.GridLayout2, 'push');
            app.ResetTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButtonPushed, true);
            app.ResetTableButton.Text = 'Reset Table';
            app.ExportDatafromPlotButton = uibutton(app.GridLayout2, 'push');
            app.ExportDatafromPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatafromPlotButtonPushed, true);
            app.ExportDatafromPlotButton.Text = 'Export Data from Plot';
            app.ExportLabel = uilabel(app.GridLayout2);
            app.ExportLabel.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'H2 Fitting')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'μmol/L or mV')
            zlabel(app.UIAxes, 'Z')
            app.UpperFit = uieditfield(app.RightPanel, 'numeric');
            app.UpperFit.ValueChangedFcn = createCallbackFcn(app, @UpperFitValueChanged, true);
            app.xLabel_2 = uilabel(app.RightPanel);
            app.xLabel_2.Text = 'Lower X';
            app.LowerFit = uieditfield(app.RightPanel, 'numeric');
            app.LowerFit.ValueChangedFcn = createCallbackFcn(app, @LowerFitValueChanged, true);
            app.xLabel = uilabel(app.RightPanel);
            app.xLabel.Text = 'Upper X';
            app.FitLimitsLabel = uilabel(app.RightPanel);
            app.FitLimitsLabel.Text = 'Fit Limits';
            app.LowerYEditFieldLabel = uilabel(app.RightPanel);
            app.LowerYEditFieldLabel.Text = 'Lower Y';
            app.LowerYEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerYEditField.ValueChangedFcn = createCallbackFcn(app, @LowerYEditFieldValueChanged, true);
            app.AxesLimitsLabel = uilabel(app.RightPanel);
            app.AxesLimitsLabel.Text = 'Axes Limits';
            app.UpperYEditFieldLabel = uilabel(app.RightPanel);
            app.UpperYEditFieldLabel.Text = 'Upper Y';
            app.UpperYEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperYEditField.ValueChangedFcn = createCallbackFcn(app, @UpperYEditFieldValueChanged, true);
            app.FitEquationTextAreaLabel = uilabel(app.RightPanel);
            app.FitEquationTextAreaLabel.Text = 'Fit Equation:';
            app.FitEquationTextArea = uitextarea(app.RightPanel);
            app.LowerXEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerXEditField.ValueChangedFcn = createCallbackFcn(app, @LowerXEditFieldValueChanged, true);
            app.xLabel_3 = uilabel(app.RightPanel);
            app.xLabel_3.Text = 'Lower X';
            app.UpperXEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperXEditField.ValueChangedFcn = createCallbackFcn(app, @UpperXEditFieldValueChanged, true);
            app.xLabel_4 = uilabel(app.RightPanel);
            app.xLabel_4.Text = 'Upper X';
            app.SpanButton = uibutton(app.RightPanel, 'push');
            app.SpanButton.ButtonPushedFcn = createCallbackFcn(app, @SpanButtonPushed, true);
            app.SpanButton.Text = 'Span';
            app.SlopeEditFieldLabel = uilabel(app.RightPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.RightPanel, 'numeric');
            app.SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeEditFieldValueChanged, true);
            app.ProcessCalcLabel = uilabel(app.RightPanel);
            app.ProcessCalcLabel.Text = 'Process Calc.';
            app.InterceptEditFieldLabel = uilabel(app.RightPanel);
            app.InterceptEditFieldLabel.Text = 'Intercept';
            app.InterceptEditField = uieditfield(app.RightPanel, 'numeric');
            app.InterceptEditField.ValueChangedFcn = createCallbackFcn(app, @InterceptEditFieldValueChanged, true);
            app.YAxisDataButtonGroup = uibuttongroup(app.RightPanel);
            app.YAxisDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YAxisDataButtonGroupSelectionChanged, true);
            app.YAxisDataButtonGroup.Title = 'Y-Axis Data';
            app.molLButton = uiradiobutton(app.YAxisDataButtonGroup);
            app.molLButton.Text = 'μmol/L';
            app.molLButton.Value = true;
            app.mVButton = uiradiobutton(app.YAxisDataButtonGroup);
            app.mVButton.Text = 'mV';
            app.nmolButton = uiradiobutton(app.YAxisDataButtonGroup);
            app.nmolButton.Text = 'nmol';
            app.VolumemLLabel = uilabel(app.RightPanel);
            app.VolumemLLabel.Text = 'Volume (mL)';
            app.VolumeEditField = uieditfield(app.RightPanel, 'numeric');
            app.VolumeEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = H2_Fitting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end