classdef H2_PanelReflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        ExportButton              matlab.ui.control.Button
        ResetTableButton          matlab.ui.control.Button
        MeanLabel                 matlab.ui.control.Label
        Mean                      matlab.ui.container.GridLayout
        Slope                     matlab.ui.control.Label
        StdDev                    matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        LoadDataButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        VolumeEditField           matlab.ui.control.NumericEditField
        VolumemlEditFieldLabel    matlab.ui.control.Label
        YAxisDataButtonGroup      matlab.ui.container.ButtonGroup
        nmolButton                matlab.ui.control.RadioButton
        mVButton                  matlab.ui.control.RadioButton
        molLButton                matlab.ui.control.RadioButton
        InterceptEditField        matlab.ui.control.NumericEditField
        InterceptEditFieldLabel   matlab.ui.control.Label
        ProcessCalcLabel          matlab.ui.control.Label
        SlopeEditField            matlab.ui.control.NumericEditField
        SlopeEditFieldLabel       matlab.ui.control.Label
        SpanButton                matlab.ui.control.Button
        xLabel_4                  matlab.ui.control.Label
        UpperXEditField           matlab.ui.control.NumericEditField
        xLabel_3                  matlab.ui.control.Label
        LowerXEditField           matlab.ui.control.NumericEditField
        FitEquationTextArea       matlab.ui.control.TextArea
        FitEquationTextAreaLabel  matlab.ui.control.Label
        UpperYEditField           matlab.ui.control.NumericEditField
        UpperYEditFieldLabel      matlab.ui.control.Label
        AxesLimitsLabel           matlab.ui.control.Label
        LowerYEditField           matlab.ui.control.NumericEditField
        LowerYEditFieldLabel      matlab.ui.control.Label
        FitLimitsLabel            matlab.ui.control.Label
        WavelengthText            matlab.ui.control.Label
        xLabel                    matlab.ui.control.Label
        LowerFit                  matlab.ui.control.NumericEditField
        xLabel_2                  matlab.ui.control.Label
        UpperFit                  matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Path % Store path to current directory
        bothY % Store Raw and Processed traces of ydata
        YData % Store the linear YData from table
        XData % Store the uniform log Xdata
        xPoints % Store the x-data points of ROI object

        ROILine = gobjects(2,1);
        Shade % Store shade array
        ShadeArea % Store shade area struct
        FitResult % Store the fit struct

        yFitData % Store fitted Ydata

        XMin % Store minimum of uniform Xdata
        XMax % Store maximum of uniform Xdata
        Curve % Store fit plot
        Data % Store scatter plot
        LogPlot % Store plot variable only when log data is loaded in

        Text % Store rate variable in text object (label)
        leftX % Store left area text object (label)
        rightX % Store right area text object (label)
        CenterY % Hold the center of all the data for labels and such
        %         gof % Holds std. dev of each fit
        processY % Property to hold the calculated YData from raw
    end

    methods (Access = private)
        function createFit(app)
            % Fit: 'Lin-Lin H2 Graph'
            % Set up fittype and options.
            excludedPoints = (app.XData < app.xPoints(1,1)) | (app.XData > app.xPoints(2,1));
            opts = fitoptions( 'Method', 'LinearLeastSquares');
            opts.Robust = 'Bisquare';
            opts.Exclude = excludedPoints;

            % Fit model to data and save data to vars
            app.FitResult = fit(app.XData, app.YData, 'poly1', opts);
            app.yFitData = feval(app.FitResult, app.XData);

            % Turn CFIT object into string
            fchar = evalc('app.FitResult');
            fstring = convertCharsToStrings(fchar);
            app.FitEquationTextArea.Value = fstring;
        end

        function shader(app)

            app.Shade = [];
            app.xPoints = [];

            %                 if ~isempty(app.UIAxes.Children)
            %                     % Change shaded area accordingly
            %                     % Store new Ylimits from button
            %                     tempY1 = app.UIAxes.YLim(1);
            %                     tempY2 = app.UIAxes.YLim(2);
            %
            %                     % Clear any existing plots
            %                     cla(app.UIAxes, 'reset');
            %
            %                     app.UIAxes.YLim(1) = tempY1;
            %                     app.UIAxes.YLim(2) = tempY2;
            %                 end

            %%%%% Default shaded area drawn/data & model
            app.XMin = min(app.XData);
            app.XMax = max(app.XData);

            YMin = min(app.YData);
            YMax = max(app.YData);

            % Draw both shaded areas up until XLims
            %             hold(app.UIAxes, 'on');
            RGarea = app.ShadeArea;

            app.Shade = gobjects(2,1);
            % Create new areas
            %                 app.Shade{1} = area(app.UIAxes, [app.XMin RGarea(1)], [YMin YMin]);
            %                 app.Shade{2} = area(app.UIAxes, [RGarea(2) app.XMax], [YMin YMin]);
            app.Shade(1,1) = rectangle(app.UIAxes,"Position", [app.XMin YMin RGarea(1,1)-app.XMin YMax-YMin],'FaceColor',[0.75 0.75 0.75]);
            app.Shade(2,1) = rectangle(app.UIAxes,"Position", [RGarea(2,1) YMin app.XMax-RGarea(2,1) YMax-YMin],'FaceColor',[0.75 0.75 0.75]);
            %                 % Set color to be same
            %                 app.Shade{1}.FaceColor = [0.75 0.75 0.75];
            %                 app.Shade{2}.FaceColor = [0.75 0.75 0.75];

            % Set default axes boundaries
            app.UIAxes.XLim = [app.XMin app.XMax];
            app.UIAxes.YLim = [YMin YMax];
            %             hold(app.UIAxes, 'off');

            % Store Lower and Upper X coordinates
            app.xPoints(1,1) = RGarea(1,1);
            app.xPoints(2,1) = RGarea(2,1);


            %%%%% End of shaded area creation

            createFit(app);
            plotModel(app);
            plotData(app);

            % Create labels
            rate = sprintf('rate=%.2f',app.FitResult.p1);

            app.Text = text(app.UIAxes, app.xPoints(2,1)*1.05, (0.90)*app.UIAxes.YLim(2), rate, 'FontWeight', 'bold');
            app.leftX = text(app.UIAxes, app.ShadeArea(1,1)*0.90, app.CenterY, sprintf('%.2f',app.ShadeArea(1,1)), 'Color', 'k', 'FontWeight',"bold", 'HorizontalAlignment',"right");
            app.rightX = text(app.UIAxes, app.ShadeArea(2,1)*1.05, app.CenterY, sprintf('%.2f',app.ShadeArea(2,1)), 'Color', 'k', 'FontWeight',"bold", 'HorizontalAlignment',"left");


            % Implement drawline for either side of the area
            if ~isnan(app.xPoints(1,1)) && ~isnan(app.xPoints(2,1))
                x = app.UIAxes.XLim(1);
                y = app.UIAxes.YLim(1)-1;
                w = app.UIAxes.XLim(2)-app.UIAxes.XLim(1);
                h = (app.UIAxes.YLim(2)*1.01)-(app.UIAxes.YLim(1)-1);

                % FOR LABEL INSTEAD OF TEXT
                app.ROILine(1,1) = images.roi.Line(app.UIAxes, "Position", [app.xPoints(1,1) app.UIAxes.YLim(1)-1; app.xPoints(1,1) app.UIAxes.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0], "DrawingArea", [x y w h],'Tag','Left');
                app.ROILine(2,1) = images.roi.Line(app.UIAxes, "Position", [app.xPoints(2,1) app.UIAxes.YLim(1)-1; app.xPoints(2,1) app.UIAxes.YLim(2)*1.01], "Deletable",false,"InteractionsAllowed","Translate",'Color', [0 0 0], "DrawingArea", [x y w h],'Tag','Right');

                % Add callbacks for when point moves
                addlistener(app.ROILine(1,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));
                addlistener(app.ROILine(2,1), "MovingROI", @(src,event)ROIChangedCallback(app,src,event));

                addlistener(app.ROILine(1,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));
                addlistener(app.ROILine(2,1), "ROIMoved", @(src,event)ROIChangedCallback(app,src,event));
            end
        end

        function default(app)
            % Update both shaded areas up until XLims
            YMin = min(app.YData);
            YMax = max(app.YData);
            RGarea = app.ShadeArea;

            % Update new areas
            app.Shade(1,1).Position = [app.XMin YMin RGarea(1,1)-app.XMin YMax-YMin];
            app.Shade(2,1).Position = [RGarea(2,1) YMin app.XMax-RGarea(2,1) YMax-YMin];

            % Store Lower and Upper X coordinates
            app.xPoints(1,1) = RGarea(1,1);
            app.xPoints(2,1) = RGarea(2,1);
        end


        function plotModel(app)
            % Plot the model and its limits
            delete(app.Curve);
            app.Curve = line(app.UIAxes, app.XData, app.yFitData, 'Color', [1 0 0],'LineWidth',1);

            % Update Fit Limits
            app.UpperFit.Value = app.xPoints(1,1);
            app.LowerFit.Value = app.xPoints(2,1);
        end

        function plotData(app)
            % Plot the data as scatter
            delete(app.Data);
            app.Data = line(app.UIAxes, app.XData, app.YData);
        end


        function updateLabels(app)
            % Change label values accordingly to the moved x pos
            app.leftX.Position = [app.ShadeArea(1,1)*0.90, app.CenterY];
            app.leftX.String = sprintf('%.2f',app.ShadeArea(1,1));
            app.rightX.Position = [app.ShadeArea(2,1)*1.05, app.CenterY];
            app.rightX.String = sprintf('%.2f',app.ShadeArea(2,1));

            % Gather updated data for updated annotation
            rate = sprintf('rate=%.2f',app.FitResult.p1);
            x = app.xPoints(2,1)*1.05;

            % Change label for slope
            app.Text.Position = [x (0.90)*app.UIAxes.YLim(2)];
            app.Text.String = rate;
        end

        function updateModel(app)
            app.Curve.XData = app.XData;
            app.Curve.YData = app.yFitData;

            app.UpperFit.Value = app.xPoints(1,1);
            app.LowerFit.Value = app.xPoints(2,1);
        end

        function ROIChangedCallback(app,src,event)
            switch event.EventName
                case 'MovingROI'
                    % If ROI area is changed through manual mouse input
                    % Replace the position of the shaded area to moved ROI pos

                    if src.Tag == "Left"
                        app.ShadeArea(1,1) = event.CurrentPosition(1);
                    else
                        app.ShadeArea(2,1) = event.CurrentPosition(2);
                    end

                    % Continue as normal
                    default(app);
                    updateLabels(app);

                case 'ROIMoved'
                    createFit(app);
                    updateModel(app);
                    updateLabels(app);
            end
        end


        function clearProperties(app)
            % Clear app and set axes properties
            cla(app.UIAxes,"reset");
            app.UIAxes.Title.String = 'H2 Fitting';
            app.UIAxes.YLabel.String = 'μmol/L or mV';
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UITable.ColumnFormat = {'logical','numeric','numeric','char'};
            app.UITable.Data = [];
            app.UITable.Data{6,4} = [];
            app.FitEquationTextArea.Value = "";
            app.SaveButton.UserData = 0;
            app.Slope.Text = "Slope: ";
            app.StdDev.Text = "Std. Dev: ";
            app.UpperFit.Value = 0;
            app.LowerFit.Value = 0;
            app.LowerXEditField.Value = 0;
            app.UpperXEditField.Value = 0;
            app.LowerYEditField.Value = 0;
            app.UpperYEditField.Value = 0;
            app.SlopeEditField.Value = 0;
            app.InterceptEditField.Value = 0;
            app.VolumeEditField.Value = 0;

            app.ShadeArea = [];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx';'*.csv';'*.xlsm';'*.xls';'*.slx';'*.*'},'File Selector');
            filevar = fullfile(path,file);
            app.Path = path;

            if file == 0
                return;
            else
                clearProperties(app);
                % H2Table = readtable(filevar); For full table
                % Or just use options to select specific columns
                opts = detectImportOptions(filevar);
                opts.SelectedVariableNames = opts.SelectedVariableNames([3,4,5]); % Only useful x and y data`
                opts.VariableNamingRule = "preserve";

                LinData = readtable(filevar,opts);

                app.XData = table2array(LinData(:,1));
                app.bothY = table2array(LinData(:,2:3)); % COLLECT RAW AND PROCESSED. FIGURE OUT HOW TO DISTINGUISH EFFICIENTLY

                if ~all(app.bothY(:,2))
                    app.YData = app.bothY(:,1);
                    %                     app.RawSwitch.Value = "On";
                    app.YAxisDataButtonGroup.SelectedObject = app.mVButton;
                    app.UIAxes.YLabel.String = "mV";
                    app.molLButton.Enable = "off"; % First time load of now processed data
                else
                    app.YData = app.bothY(:,2);
                    %                     s.Value = "Off";
                    app.YAxisDataButtonGroup.SelectedObject = app.molLButton;
                    app.UIAxes.YLabel.String = "μmol/L";
                    app.molLButton.Enable = "on";
                    %                     originalYProc = app.YData;
                end

                %                 app.IndexSize = width(LinData);
                app.CenterY = mean(app.YData);
                CenterTime = mean(app.XData);

                if CenterTime > 0
                    app.ShadeArea = [CenterTime/10; CenterTime+100];
                else
                    app.ShadeArea = [CenterTime+100; CenterTime/10];
                end

                shader(app);

                % Set GUI elements to reflect data limits
                app.LowerYEditField.Value = app.UIAxes.YLim(1);
                app.UpperYEditField.Value = app.UIAxes.YLim(2);
                app.LowerXEditField.Value = app.UIAxes.XLim(1);
                app.UpperXEditField.Value = app.UIAxes.XLim(2);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.SaveButton.UserData = app.SaveButton.UserData + 1;

            % Extract slope & calc. std. dev.
            coeffvals = coeffvalues(app.FitResult);
            slope = coeffvals(1);

            units = [app.UIAxes.YLabel.String, ' * 1/s'];

            % Create table entry
            data = {1,app.SaveButton.UserData,slope,units};
            app.UITable.Data(app.SaveButton.UserData,:) = data;

            % Find only the checked rows
            k = find(cell2mat(app.UITable.Data(:,1)));

            % Find mean slope
            avgslope = cell2mat(app.UITable.Data(k,3));
            meanslope = sum(avgslope,'all')/size(avgslope,1);

            % Find deviation from selected slopes
            dev = std(avgslope);

            app.Slope.Text = "Slope: " + num2str(meanslope,'%.4f');
            app.StdDev.Text = "Std. Dev: " + num2str(dev,'%.4f');
        end

        % Callback function
        function ExportButtonPushed(app, event)
            % Store necessary variables for export
            time = app.XData;
            abs = app.YData;
            yfit = app.yFitData;
            fitwindow = [app.xPoints(1,1); app.xPoints(2,1)];
            fiteqn = [app.FitResult.p1; app.FitResult.p2];

            % Create blank array with titles
            p = {'LogTime', 'LogABS', 'yfitData', 'fitWindow', 'fitEquation'};
            A = nan(size(time,1),5);
            A(1:length(time),1) = time;
            A(1:length(abs),2) = abs;
            A(1:length(yfit),3) = yfit;
            A(1:length(fitwindow),4) = fitwindow;
            A(1:length(fiteqn),5) = fiteqn;

            % Convert 5 column vectors into table with appropriate names
            T = array2table(A);
            T.Properties.VariableNames(1:5) = p;

            % Add wavelength into title of filename
            %             f = sprintf('H2LinData%s.csv',app.WavelengthText.Text);
            f = 'H2LinData.csv';
            place = fullfile(app.Path,f);
            writetable(T,place);
        end

        % Value changed function: UpperFit
        function UpperFitValueChanged(app, event)
            %             value = app.UpperFit.Value;
            latestvalue = event.Value;

            % Keep the changed input within the boundary conditons
            if (latestvalue < app.XMin)
                latestvalue = app.XMin; %#ok<NASGU>
            elseif (latestvalue > app.ROILine(2,1).Position(1))
                latestvalue = app.ROILine(2,1).Position(1) + 1; %#ok<NASGU>
            else
                % Code to snap to nearest data point
                app.xPoints(1,1) = latestvalue;
                [~, line_index] = min(abs(app.XData - latestvalue));
                closest = app.XData(line_index);

                app.ROILine(1,1).Position = [closest app.UIAxes.YLim(1)-1; closest app.UIAxes.YLim(2)*1.01];
                %                 app.ROILine(1,1).Label = sprintf('%.6f',closest);

                app.ShadeArea = [closest; app.xPoints(2,1)];

                updateLabels(app);
                default(app);
                createFit(app);
                updateModel(app);
            end
        end

        % Value changed function: LowerFit
        function LowerFitValueChanged(app, event)
            %             value = app.LowerFit.Value;
            latestvalue = event.Value;

            % Keep the changed input within the boundary conditons
            if (latestvalue > app.XMax)
                latestvalue = app.XMax; %#ok<NASGU>
            elseif (latestvalue < app.ROILine(1,1).Position(1))
                latestvalue = app.ROILine(1,1).Position(1) - 1; %#ok<NASGU>
            else
                % Code to snap to nearest data point
                app.xPoints(2,1) = latestvalue;

                [~, line_index] = min(abs(app.XData - latestvalue));
                closest = app.XData(line_index);

                app.ROILine(2,1).Position = [closest app.UIAxes.YLim(1)-1; closest app.UIAxes.YLim(2)*1.01];
                %                 app.ROILine(2,1).Label = sprintf('%.6f',closest);

                app.ShadeArea = [app.xPoints(1,1); closest];
                updateLabels(app);
                default(app);
                createFit(app);
                updateModel(app);
            end
        end

        % Value changed function: LowerYEditField
        function LowerYEditFieldValueChanged(app, event)
            value = app.LowerYEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.YLim(1) = value;
            end
        end

        % Value changed function: UpperYEditField
        function UpperYEditFieldValueChanged(app, event)
            value = app.UpperYEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.YLim(2) = value;
            end
        end

        % Callback function
        function RawSwitchValueChanged(app, event)
            %             value = app.RawSwitch.Value;
            %
            %             if value == "Off"
            %                 if all(app.bothY(:,2))
            %                     app.YData = app.bothY(:,2);
            %                     app.UIAxes.YLabel.String = 'μmol/L';
            %                 else
            %                     uialert(app.UIFigure,"The processed trace is empty. Reverting back to raw trace.","Process Check","Icon","warning");
            %                     return;
            %                 end
            %             else
            %                 app.YData = app.bothY(:,1);
            %                 app.UIAxes.YLabel.String = 'mV';
            %             end
            %
            %             app.Data.YData = app.YData;
            %
            %             createFit(app);
            %             updateModel(app);
            %
            %             % Update the YLims and reflect on GUI elements
            %             app.UIAxes.YLim(2) = max(app.YData);
            %             app.UIAxes.YLim(1) = min(app.YData);
            %             app.UpperYEditField.Value = app.UIAxes.YLim(2);
            %             app.LowerYEditField.Value = app.UIAxes.YLim(1);
            %
            %             % Update shade areas and line to new YLims
            %             app.Shade{1}.YData = [app.UIAxes.YLim(1) app.UIAxes.YLim(1)];
            %             app.Shade{2}.YData = [app.UIAxes.YLim(1) app.UIAxes.YLim(1)];
            %
            %             app.ROILine(1,1).Position = [app.xPoints(1,1) app.UIAxes.YLim(1)-1; app.xPoints(1,1) app.UIAxes.YLim(2)*1.01];
            %             app.ROILine(2,1).Position = [app.xPoints(1,2) app.UIAxes.YLim(1)-1; app.xPoints(1,2) app.UIAxes.YLim(2)*1.01];
        end

        % Value changed function: UpperXEditField
        function UpperXEditFieldValueChanged(app, event)
            value = app.UpperXEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.XLim(2) = value;
                %                 app.Shade(2,1).Position(3) = app.Shade(2,1).Position(1) - value;
            end
        end

        % Value changed function: LowerXEditField
        function LowerXEditFieldValueChanged(app, event)
            value = app.LowerXEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.XLim(1) = value;
                %                 app.Shade(1,1).Position(1) = value;
                %                 app.Shade(1,1).Position(3) = abs(value - app.xPoints(1,1));
            end
        end

        % Button pushed function: SpanButton
        function SpanButtonPushed(app, event)
            % Automatically find limitx excluding fit model
            app.UIAxes.XLimMode = "auto";
            app.UIAxes.XLimitMethod = "tight";

            app.Curve.Visible = false; % Remove since it spans all data points

            app.UIAxes.YLimMode = "auto";
            app.UIAxes.YLimitMethod = "tight";


            % Apply these limits to reflect GUI values
            app.LowerXEditField.Value = app.UIAxes.XLim(1);
            app.UpperXEditField.Value = app.UIAxes.XLim(2);
            app.LowerYEditField.Value = app.UIAxes.YLim(1);
            app.UpperYEditField.Value = app.UIAxes.YLim(2);

            % Revert back to manual so when the model changes it doesn't
            % change limits
            app.UIAxes.XLimMode = "manual";
            app.UIAxes.YLimMode = "manual";
            app.Curve.Visible = true;
        end

        % Selection changed function: YAxisDataButtonGroup
        function YAxisDataButtonGroupSelectionChanged(app, event)
            selectedButton = app.YAxisDataButtonGroup.SelectedObject;

            switch selectedButton.Text
                case "μmol/L"
                    if all(app.bothY(:,2))
                        app.YData = app.bothY(:,2);
                        app.UIAxes.YLabel.String = 'μmol/L';
                    else
                        uialert(app.UIFigure,"The processed trace is empty, please calculate with calibration. Reverting back to raw trace.","Process Check","Icon","warning");
                        return;
                    end
                case "mV"
                    app.YData = app.bothY(:,1);
                    app.UIAxes.YLabel.String = 'mV';

                case "nmol"
                    value = app.VolumeEditField.Value;
                    if value == 0

                        app.Data.YData = app.bothY(:,2);
                        app.UIAxes.YLabel.String = "μmol/L";
                        return;
                    else
                        app.bothY(:,3) = value*app.bothY(:,2);
                        app.YData = app.bothY(:,3);
                        app.Data.YData = app.YData/1000;
                        app.YAxisDataButtonGroup.SelectedObject = app.nmolButton;
                        app.UIAxes.YLabel.String = "nmol";
                    end
            end

            % Update plot YData
            app.Data.YData = app.YData;
            YMax = max(app.YData);

            RGarea = app.ShadeArea;

            createFit(app);
            updateModel(app);

            % Update the YLims and reflect on GUI elements
            app.UIAxes.YLim(2) = max(app.YData);
            app.UIAxes.YLim(1) = min(app.YData);
            app.UpperYEditField.Value = app.UIAxes.YLim(2);
            app.LowerYEditField.Value = app.UIAxes.YLim(1);

            % Update shade areas and line to new YLims
            app.Shade(1,1).Position = [app.XMin app.UIAxes.YLim(1) RGarea(1,1)-app.XMin YMax-app.UIAxes.YLim(1)];
            app.Shade(2,1).Position = [RGarea(2,1) app.UIAxes.YLim(1) app.XMax-RGarea(2,1) YMax-app.UIAxes.YLim(1)];

            app.ROILine(1,1).Position = [app.xPoints(1,1) app.UIAxes.YLim(1)-1; app.xPoints(1,1) app.UIAxes.YLim(2)*1.01];
            app.ROILine(2,1).Position = [app.xPoints(2,1) app.UIAxes.YLim(1)-1; app.xPoints(2,1) app.UIAxes.YLim(2)*1.01];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %             indices = event.Indices;
            %             newData = event.NewData;

            %             % Find size of fit data being used to calc std. dev.
            %             [~, line_index1] = min(abs(app.XData - app.xPoints(1,1)));
            %             [~, line_index2] = min(abs(app.XData - app.xPoints(1,2)));
            %             N = size(app.yFitData(line_index1:line_index2),1);


            % Find only the checked rows
            k = find(cell2mat(app.UITable.Data(:,1)));

            % Find mean slope
            avgslope = cell2mat(app.UITable.Data(k,3));
            meanslope = sum(avgslope,'all')/size(avgslope,1);

            %             % Find mean dev
            %             avgdev = cell2mat(app.UITable.Data(k,4));
            %             meandev = sum(avgdev,'all')/size(avgdev,1);
            dev = std(avgslope);

            app.Slope.Text = "Slope: " + num2str(meanslope,'%.4f');
            app.StdDev.Text = "Std. Dev: " + num2str(dev,'%.4f');
        end

        % Button pushed function: ResetTableButton
        function ResetTableButtonPushed(app, event)
            app.UITable.Data = [];
            app.UITable.Data{6,4} = [];
            app.SaveButton.UserData = 0;

            app.Slope.Text = "Slope: ";
            app.StdDev.Text = "Std. Dev: ";
        end

        % Value changed function: SlopeEditField
        function SlopeEditFieldValueChanged(app, event)
            value = app.SlopeEditField.Value;

            if value == 0
                return;
            else
                app.molLButton.Enable = "on";
                app.YAxisDataButtonGroup.SelectedObject = app.molLButton;

                app.bothY(:,2) = (app.bothY(:,1) - app.InterceptEditField.Value)/app.SlopeEditField.Value;
                app.YData = app.bothY(:,2);

                YAxisDataButtonGroupSelectionChanged(app);
            end
        end

        % Value changed function: InterceptEditField
        function InterceptEditFieldValueChanged(app, event)
            %             value = app.InterceptEditField.Value;
            app.bothY(:,2) = (app.bothY(:,1) - app.InterceptEditField.Value)/app.SlopeEditField.Value;
            app.YData = app.bothY(:,2);
            YAxisDataButtonGroupSelectionChanged(app);
        end

        % Value changed function: VolumeEditField
        function VolumeEditFieldValueChanged(app, event)
            %                         value = app.VolumeEditField.Value;
            app.YAxisDataButtonGroup.SelectedObject = app.nmolButton;
            YAxisDataButtonGroupSelectionChanged(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {530, 530};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'H2 Fitting';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'✓'; 'Save'; 'Slope'; 'Units'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Mean = uigridlayout(app.GridLayout2);
            app.StdDev = uilabel(app.Mean);
            app.StdDev.Text = 'Std. Dev:';
            app.Slope = uilabel(app.Mean);
            app.Slope.Text = 'Slope:';
            app.MeanLabel = uilabel(app.GridLayout2);
            app.MeanLabel.Text = 'MEAN';
            app.ResetTableButton = uibutton(app.GridLayout2, 'push');
            app.ResetTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButtonPushed, true);
            app.ResetTableButton.Text = 'Reset Table';
            app.ExportButton = uibutton(app.GridLayout2, 'push');
            app.ExportButton.Text = 'Export';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'H2 Fitting')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'μmol/L or mV')
            zlabel(app.UIAxes, 'Z')
            app.UpperFit = uieditfield(app.RightPanel, 'numeric');
            app.UpperFit.ValueChangedFcn = createCallbackFcn(app, @UpperFitValueChanged, true);
            app.xLabel_2 = uilabel(app.RightPanel);
            app.xLabel_2.Text = 'Lower X';
            app.LowerFit = uieditfield(app.RightPanel, 'numeric');
            app.LowerFit.ValueChangedFcn = createCallbackFcn(app, @LowerFitValueChanged, true);
            app.xLabel = uilabel(app.RightPanel);
            app.xLabel.Text = 'Upper X';
            app.WavelengthText = uilabel(app.RightPanel);
            app.WavelengthText.Text = '';
            app.FitLimitsLabel = uilabel(app.RightPanel);
            app.FitLimitsLabel.Text = 'Fit Limits';
            app.LowerYEditFieldLabel = uilabel(app.RightPanel);
            app.LowerYEditFieldLabel.Text = 'Lower Y';
            app.LowerYEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerYEditField.ValueChangedFcn = createCallbackFcn(app, @LowerYEditFieldValueChanged, true);
            app.AxesLimitsLabel = uilabel(app.RightPanel);
            app.AxesLimitsLabel.Text = 'Axes Limits';
            app.UpperYEditFieldLabel = uilabel(app.RightPanel);
            app.UpperYEditFieldLabel.Text = 'Upper Y';
            app.UpperYEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperYEditField.ValueChangedFcn = createCallbackFcn(app, @UpperYEditFieldValueChanged, true);
            app.FitEquationTextAreaLabel = uilabel(app.RightPanel);
            app.FitEquationTextAreaLabel.Text = 'Fit Equation:';
            app.FitEquationTextArea = uitextarea(app.RightPanel);
            app.LowerXEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerXEditField.ValueChangedFcn = createCallbackFcn(app, @LowerXEditFieldValueChanged, true);
            app.xLabel_3 = uilabel(app.RightPanel);
            app.xLabel_3.Text = 'Lower X';
            app.UpperXEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperXEditField.ValueChangedFcn = createCallbackFcn(app, @UpperXEditFieldValueChanged, true);
            app.xLabel_4 = uilabel(app.RightPanel);
            app.xLabel_4.Text = 'Upper X';
            app.SpanButton = uibutton(app.RightPanel, 'push');
            app.SpanButton.ButtonPushedFcn = createCallbackFcn(app, @SpanButtonPushed, true);
            app.SpanButton.Text = 'Span';
            app.SlopeEditFieldLabel = uilabel(app.RightPanel);
            app.SlopeEditFieldLabel.Text = 'Slope';
            app.SlopeEditField = uieditfield(app.RightPanel, 'numeric');
            app.SlopeEditField.ValueChangedFcn = createCallbackFcn(app, @SlopeEditFieldValueChanged, true);
            app.ProcessCalcLabel = uilabel(app.RightPanel);
            app.ProcessCalcLabel.Text = 'Process Calc.';
            app.InterceptEditFieldLabel = uilabel(app.RightPanel);
            app.InterceptEditFieldLabel.Text = 'Intercept';
            app.InterceptEditField = uieditfield(app.RightPanel, 'numeric');
            app.InterceptEditField.ValueChangedFcn = createCallbackFcn(app, @InterceptEditFieldValueChanged, true);
            app.YAxisDataButtonGroup = uibuttongroup(app.RightPanel);
            app.YAxisDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YAxisDataButtonGroupSelectionChanged, true);
            app.YAxisDataButtonGroup.Title = 'Y-Axis Data';
            app.molLButton = uiradiobutton(app.YAxisDataButtonGroup);
            app.molLButton.Text = 'μmol/L';
            app.molLButton.Value = true;
            app.mVButton = uiradiobutton(app.YAxisDataButtonGroup);
            app.mVButton.Text = 'mV';
            app.nmolButton = uiradiobutton(app.YAxisDataButtonGroup);
            app.nmolButton.Text = 'nmol';
            app.VolumemlEditFieldLabel = uilabel(app.RightPanel);
            app.VolumemlEditFieldLabel.Text = 'Volume (ml)';
            app.VolumeEditField = uieditfield(app.RightPanel, 'numeric');
            app.VolumeEditField.ValueChangedFcn = createCallbackFcn(app, @VolumeEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = H2_PanelReflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end